classdef DOS_vj2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        UlaznisignalTab              matlab.ui.container.Tab
        UreduButton                  matlab.ui.control.Button
        FrekvencijaEditFieldLabel    matlab.ui.control.Label
        FrekvencijaEditField         matlab.ui.control.NumericEditField
        AmplitudaEditFieldLabel      matlab.ui.control.Label
        AmplitudaEditField           matlab.ui.control.NumericEditField
        inpFuncDraw                  matlab.ui.control.UIAxes
        ValnioblikDropDownLabel      matlab.ui.control.Label
        ValnioblikDropDown           matlab.ui.control.DropDown
        UzorkovanjeTab               matlab.ui.container.Tab
        UreduButton_2                matlab.ui.control.Button
        FrekvencijauzorkovanjaEditFieldLabel  matlab.ui.control.Label
        FrekvencijauzorkovanjaEditField  matlab.ui.control.NumericEditField
        FrekvencijasignalaEditFieldLabel  matlab.ui.control.Label
        FrekvencijasignalaEditField  matlab.ui.control.EditField
        samplFunc                    matlab.ui.control.UIAxes
        RekonstrukcijaTab            matlab.ui.container.Tab
        rekon                        matlab.ui.control.UIAxes
        FTTab                        matlab.ui.container.Tab
        ft                           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UreduButton
        function UreduButtonPushed(app, event)
            p = round(pi, 4, 'significant');
            a = get(app.AmplitudaEditField, 'Value');
            f = get(app.FrekvencijaEditField, 'Value');
            
            period = 1/f;
            
            t = 0:0.000001:(4*period);
            w = 2*p*f;
            
            switch app.ValnioblikDropDown.Value
                case 'sin'
                    y = a*sin(w*t);
                case 'cos'
                    y = a*cos(w*t);
                case 'trokut'
                    y = a*sawtooth(w*t);  
                case 'pravokutnik'
                    y = a*square(w*t);  
            end
            
           
            app.FrekvencijasignalaEditField.Value = int2str(f);
            app.FrekvencijauzorkovanjaEditField.Value = f*2;
            plot(app.inpFuncDraw, t, y);
            UreduButton_2Pushed(app, event);
           
        end

        % Button pushed function: UreduButton_2
        function UreduButton_2Pushed(app, event)
            hold(app.samplFunc, 'off');
            p = round(pi, 4, 'significant');
            a = get(app.AmplitudaEditField, 'Value');
            f = get(app.FrekvencijaEditField, 'Value');
            samplf = get(app.FrekvencijauzorkovanjaEditField, 'Value');
            
            period = 1/f;
            samplPeriod  = 1/samplf;
            
            t = 0:0.0000001:(4*period);
            n = 0:samplPeriod:(4*period);
            w = 2*p*f;
            
            %y = a*sin(w*t);
           % x = a*sin(w*n);
            
            switch app.ValnioblikDropDown.Value
                case 'sin'
                    y = a*sin(w*t);
                    x = a*sin(w*n);
                case 'cos'
                    y = a*cos(w*t);
                    x = a*cos(w*n);
                case 'trokut'
                    y = a*sawtooth(w*t);
                    x = a*sawtooth(w*n);
                case 'pravokutnik'
                    y = a*square(w*t);
                    x = a*square(w*n);                  
            end
            
            plot(app.samplFunc,t, y);
            hold(app.samplFunc, 'on');
            stem(app.samplFunc,n,x);
            plot(app.rekon, n, x);
            
            y_fft = fft(y,10);
            x_fft = fft(x,10);
            stem(app.ft, y_fft);
            hold(app.ft, 'on');
           % fftshift(y_fft);
            stem(app.ft, x_fft);
            hold(app.ft, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UlaznisignalTab = uitab(app.TabGroup);
            app.UlaznisignalTab.Title = 'Ulazni signal';
            app.UreduButton = uibutton(app.UlaznisignalTab, 'push');
            app.UreduButton.ButtonPushedFcn = createCallbackFcn(app, @UreduButtonPushed, true);
            app.UreduButton.Text = 'Uredu';
            app.FrekvencijaEditFieldLabel = uilabel(app.UlaznisignalTab);
            app.FrekvencijaEditFieldLabel.Text = 'Frekvencija';
            app.FrekvencijaEditField = uieditfield(app.UlaznisignalTab, 'numeric');
            app.FrekvencijaEditField.Limits = [1 10000];
            app.FrekvencijaEditField.Value = 50;
            app.AmplitudaEditFieldLabel = uilabel(app.UlaznisignalTab);
            app.AmplitudaEditFieldLabel.Text = 'Amplituda';
            app.AmplitudaEditField = uieditfield(app.UlaznisignalTab, 'numeric');
            app.AmplitudaEditField.Limits = [1 Inf];
            app.AmplitudaEditField.Value = 1;
            app.inpFuncDraw = uiaxes(app.UlaznisignalTab);
            title(app.inpFuncDraw, 'Ulazna funkcija')
            xlabel(app.inpFuncDraw, 'Vrijeme')
            ylabel(app.inpFuncDraw, 'Amplituda')
            app.ValnioblikDropDownLabel = uilabel(app.UlaznisignalTab);
            app.ValnioblikDropDownLabel.Text = 'Valni oblik';
            app.ValnioblikDropDown = uidropdown(app.UlaznisignalTab);
            app.ValnioblikDropDown.Items = {'sin', 'cos', 'trokut', 'pravokutnik'};
            app.ValnioblikDropDown.Value = 'sin';
            app.UzorkovanjeTab = uitab(app.TabGroup);
            app.UzorkovanjeTab.Title = 'Uzorkovanje';
            app.UreduButton_2 = uibutton(app.UzorkovanjeTab, 'push');
            app.UreduButton_2.ButtonPushedFcn = createCallbackFcn(app, @UreduButton_2Pushed, true);
            app.UreduButton_2.Text = 'Uredu';
            app.FrekvencijauzorkovanjaEditFieldLabel = uilabel(app.UzorkovanjeTab);
            app.FrekvencijauzorkovanjaEditFieldLabel.Text = 'Frekvencija uzorkovanja';
            app.FrekvencijauzorkovanjaEditField = uieditfield(app.UzorkovanjeTab, 'numeric');
            app.FrekvencijauzorkovanjaEditField.Limits = [1 Inf];
            app.FrekvencijauzorkovanjaEditField.RoundFractionalValues = 'on';
            app.FrekvencijauzorkovanjaEditField.ValueDisplayFormat = '%.0f';
            app.FrekvencijauzorkovanjaEditField.Value = 1;
            app.FrekvencijasignalaEditFieldLabel = uilabel(app.UzorkovanjeTab);
            app.FrekvencijasignalaEditFieldLabel.Text = 'Frekvencija signala';
            app.FrekvencijasignalaEditField = uieditfield(app.UzorkovanjeTab, 'text');
            app.FrekvencijasignalaEditField.Editable = 'off';
            app.samplFunc = uiaxes(app.UzorkovanjeTab);
            title(app.samplFunc, 'Uzorkovani signal')
            xlabel(app.samplFunc, 'Vrijeme')
            ylabel(app.samplFunc, 'Amplituda')
            app.RekonstrukcijaTab = uitab(app.TabGroup);
            app.RekonstrukcijaTab.Title = 'Rekonstrukcija';
            app.rekon = uiaxes(app.RekonstrukcijaTab);
            title(app.rekon, 'Rekonstruirani signal')
            xlabel(app.rekon, 'Vrijeme')
            ylabel(app.rekon, 'Amplituda')
            app.FTTab = uitab(app.TabGroup);
            app.FTTab.Title = 'FT';
            app.ft = uiaxes(app.FTTab);
            title(app.ft, 'Title')
            xlabel(app.ft, 'X')
            ylabel(app.ft, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOS_vj2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end